classdef dsp_integrate_e_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Lamp                     matlab.ui.control.Lamp
        Button_15                matlab.ui.control.Button
        Button_14                matlab.ui.control.Button
        Button_13                matlab.ui.control.Button
        Button_12                matlab.ui.control.Button
        Button_11                matlab.ui.control.Button
        Button_10                matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        Label_6                  matlab.ui.control.Label
        EditField2               matlab.ui.control.NumericEditField
        Button_6                 matlab.ui.control.Button
        DropDown                 matlab.ui.control.DropDown
        Label_2                  matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        Label                    matlab.ui.control.Label
        Tab_2                    matlab.ui.container.Tab
        Button_16                matlab.ui.control.Button
        Label_11                 matlab.ui.control.Label
        EditField4               matlab.ui.control.NumericEditField
        Label_10                 matlab.ui.control.Label
        EditField3               matlab.ui.control.NumericEditField
        DropDown_3               matlab.ui.control.DropDown
        Label_9                  matlab.ui.control.Label
        EditField_2              matlab.ui.control.NumericEditField
        Label_8                  matlab.ui.control.Label
        DropDown_2               matlab.ui.control.DropDown
        Label_7                  matlab.ui.control.Label
        Tab_5                    matlab.ui.container.Tab
        frame_durEditField       matlab.ui.control.EditField
        frame_durEditFieldLabel  matlab.ui.control.Label
        VADetaEditField          matlab.ui.control.EditField
        VADetaEditFieldLabel     matlab.ui.control.Label
        muEditField              matlab.ui.control.EditField
        muEditFieldLabel         matlab.ui.control.Label
        Button_18                matlab.ui.control.Button
        Tab_3                    matlab.ui.container.Tab
        Slider_3                 matlab.ui.control.Slider
        Label_4                  matlab.ui.control.Label
        Slider_2                 matlab.ui.control.Slider
        Label_3                  matlab.ui.control.Label
        Button_7                 matlab.ui.control.Button
        Tab_4                    matlab.ui.container.Tab
        Slider_5                 matlab.ui.control.Slider
        Slider_5Label            matlab.ui.control.Label
        Slider_4                 matlab.ui.control.Slider
        Label_12                 matlab.ui.control.Label
        Button_17                matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        Label_5                  matlab.ui.control.Label
        Button_3                 matlab.ui.control.Button
        ButtonGroup              matlab.ui.container.ButtonGroup
        Button_1                 matlab.ui.control.ToggleButton
        Button_2                 matlab.ui.control.ToggleButton
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
%         Property2 % Description

        file="ttsmaker-file-2023-12-23-20-30-29.mp3";
        folder="D:\workplace\matlab\dsp_integrate_experiment\";
        y1;
        fs1;
        y2;

        ffty1;

        ffty2;

        recObj = audiorecorder;
    end
    
    methods (Access = private)
        
        function to_one(app)
            app.y1 = sum(app.y1,2)/2;
        end
        
        function [f, mag] = seq2f2mag(~,x,Fs)
            % 读取音频文件
            % 计算FFT并获取幅度谱
            N = length(x);
            n = pow2(nextpow2(N));  % transform length
            X = fft(x,n);
%             mag = 20*log10(abs(X(1:n/2+1)));
            mag = abs(X(1:n/2+1)).^2/n;
            % 创建频率向量
            f = Fs*(0:(n/2))/n;
        end
        
        function draw_output(app)
            if app.Button_1.Value
                plot(app.UIAxes_2, linspace(0,(length(app.y2)/app.fs1),length(app.y2)), app.y2 )
                xlabel(app.UIAxes_2, 't/s')
                ylabel(app.UIAxes_2, 'Amp.')
            elseif app.Button_2.Value
                [f,mag]=app.seq2f2mag(app.y2,app.fs1);
                plot(app.UIAxes_2, f,mag)
                xlabel(app.UIAxes_2, 'f/Hz')
                ylabel(app.UIAxes_2, 'Mag.')
            end
        end
        
        function draw_input(app)
            if app.Button_1.Value
                plot(app.UIAxes_3, linspace(0,(length(app.y1)/app.fs1),length(app.y1)), app.y1 )
                xlabel(app.UIAxes_3, 't/s')
                ylabel(app.UIAxes_3, 'Amp.')
            elseif app.Button_2.Value
                [f,mag]=app.seq2f2mag(app.y1,app.fs1);
                plot(app.UIAxes_3, f ,mag )
                xlabel(app.UIAxes_3, 'f/Hz')
                ylabel(app.UIAxes_3, 'Mag.')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            try
                [app.file,app.folder]=uigetfile('*.mp3;*.wav;*.m4a');
                app.EditField.Value=strcat(app.folder,app.file);
            catch 
                fprintf("未选")
            end

        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            try
                [app.y1,app.fs1]=audioread(strcat(app.folder,app.file));
                app.to_one()
                app.draw_input()
            catch e
                e
                warndlg("输入无效")
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            try
                cata=app.DropDown.Value;    
                if cata=="平均"
                    app.y2=rand(length(app.y1),1)*2*app.Slider.Value*max(app.y1)+app.y1;
                elseif cata=="高斯"
                    app.y2=randn(length(app.y1),1)*app.Slider.Value*max(app.y1)+app.y1;
                elseif cata=="正弦"
                    f=app.EditField2.Value;
                    app.y2=cos(2*pi*f*(1:length(app.y1))/app.fs1)'*app.Slider.Value*max(app.y1)+app.y1;
%                 else
%                     warndlg("未处理")
%                     return
                end
                
                app.draw_output();
               
            catch e
                e
                warndlg("未处理")
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if ~isempty(app.y1)
                sound(app.y1,app.fs1)
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value=="正弦"
                app.EditField2.Enable=1;
            else
                app.EditField2.Enable=0;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if ~isempty(app.y1)
                app.draw_input()
            end

            if ~isempty(app.y2)
                app.draw_output()
            end

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if ~isempty(app.y2)
                app.y1=app.y2;
                app.draw_input()
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if isempty(app.y1)
                return
            end
            if size(app.y1,1)<size(app.y1,2)
                app.y1=app.y1';
            end
            app.y2=[zeros(floor(app.Slider_3.Value*app.fs1),1);app.Slider_2.Value.*app.y1]+...
                    [app.y1;zeros(floor(app.Slider_3.Value*app.fs1),1)];
            app.y2=app.y2(1:length(app.y1));
            app.draw_output();
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if ~isempty(app.y2)
                sound(app.y2,app.fs1)
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            clear sound
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            clear sound
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            record(app.recObj);
            disp('Recording in progress now ...')
            app.Lamp.Color='r';
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            try
                windowname=app.DropDown_2.Value;
                cata=app.DropDown_3.Value;
                n=app.EditField2.Value;

                if windowname=="海明"
                    window=hamming(n+1);
                elseif windowname=="布莱克曼"
                    window=blackman(n+1);
                elseif windowname=="矩形"
                    window=ones(1,n+1);
                end

%                 user_filter=fir1()
                if cata=="高通"
                    ll=app.EditField3.Value/app.fs1*2;
                    user_filter=fir1(n,ll,"high",window);
                    app.y2=filter(user_filter,1,app.y1);

                elseif cata=="低通"
                    ul=app.EditField4.Value/app.fs1*2;
                    user_filter=fir1(n,ul,"low",window);
                    app.y2=filter(user_filter,1,app.y1);

                elseif cata=="带通"
                    ul=app.EditField4.Value/app.fs1*2;
                    ll=app.EditField3.Value/app.fs1*2;
                    user_filter=fir1(n,[ll ul],"bandpass",window);
                    app.y2=filter(user_filter,1,app.y1);

                elseif cata=="带阻"
                    ul=app.EditField4.Value/app.fs1*2;
                    ll=app.EditField3.Value/app.fs1*2;
                    user_filter=fir1(n,[ll ul],"stop",window);
                    app.y2=filter(user_filter,1,app.y1);

                else
                    warndlg("未处理")
                    return
                end
                
                app.draw_output();
               
            catch e
                e
                warndlg("未处理")
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            cata = app.DropDown_3.Value;

            if cata=="高通"
                app.EditField3.Enable=1;
                app.EditField4.Enable=0;
            elseif cata=="低通"
                app.EditField3.Enable=0;
                app.EditField4.Enable=1;

            elseif cata=="带通"
                app.EditField3.Enable=1;
                app.EditField4.Enable=1;

            elseif cata=="带阻"
                app.EditField3.Enable=1;
                app.EditField4.Enable=1;

            else
                warndlg("未处理")
                return
            end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            try
                stop(app.recObj)
                app.y1=getaudiodata(app.recObj);
                app.fs1=8000;
                app.draw_input()
                app.Lamp.Color='black';
            catch e
                e
            end
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            try
                slidernum=app.Slider_4.Value;
                if slidernum<0
                    alpha=-5*slidernum;
                elseif slidernum>0
                    alpha=1/(1+5*slidernum);
                else
                    alpha=1;
                end

                app.y2=voice(app.y1,alpha);
                [q,p]=rat(app.Slider_5.Value);
                app.y2=resample(app.y2,p,q);
                app.draw_output()
            catch e
                e
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            try
                audiowrite(strcat("-1-",app.file,".wav"),app.y2,app.fs1)
            catch e
                e
            end
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            try
                if size(app.y1,1)<size(app.y1,2)
                    app.y1=app.y1';
                end
                mu=str2double(app.muEditField.Value);
                eta=str2double(app.VADetaEditField.Value);
                fr=str2double(app.frame_durEditField.Value);
                app.y2=wiener_as(app.y1,app.fs1,mu,eta,fr);
                app.draw_output()
            catch e
                e
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '处理后')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '处理前')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '可视分析';
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '频域';
            app.Button_2.Value = true;
            app.Button_1 = uitogglebutton(app.ButtonGroup);
            app.Button_1.Text = '时域';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '选择';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '文件路径';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'D:\workplace\matlab\dsp_integrate_experiment\ttsmaker-file-2023-12-23-20-30-29.mp3';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '加噪';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '噪声增益';
            app.Slider = uislider(app.Tab);
            app.Slider.Limits = [0 0.4];
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '噪声分布';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'平均', '高斯', '正弦'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '平均';
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '处理分析';
            app.EditField2 = uieditfield(app.Tab, 'numeric');
            app.EditField2.Limits = [0 20000];
            app.EditField2.Enable = 'off';
            app.EditField2.Value = 1800;
            app.Label_6 = uilabel(app.Tab);
            app.Label_6.Text = '频率';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '滤波';
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '窗函数选择';
            app.DropDown_2 = uidropdown(app.Tab_2);
            app.DropDown_2.Items = {'海明', '布莱克曼', '矩形'};
            app.DropDown_2.Value = '海明';
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '阶数';
            app.EditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_2.Value = 48;
            app.Label_9 = uilabel(app.Tab_2);
            app.Label_9.Text = '类型';
            app.DropDown_3 = uidropdown(app.Tab_2);
            app.DropDown_3.Items = {'高通', '低通', '带通', '带阻'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '带通';
            app.EditField3 = uieditfield(app.Tab_2, 'numeric');
            app.EditField3.Limits = [0 10000];
            app.EditField3.Value = 20;
            app.Label_10 = uilabel(app.Tab_2);
            app.Label_10.Text = '下限频率';
            app.EditField4 = uieditfield(app.Tab_2, 'numeric');
            app.EditField4.Limits = [0 50000];
            app.EditField4.Value = 5000;
            app.Label_11 = uilabel(app.Tab_2);
            app.Label_11.Text = '上限频率';
            app.Button_16 = uibutton(app.Tab_2, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '处理分析';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '噪声抑制';
            app.Button_18 = uibutton(app.Tab_5, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '处理分析';
            app.muEditFieldLabel = uilabel(app.Tab_5);
            app.muEditFieldLabel.Text = '平滑因子（mu）';
            app.muEditField = uieditfield(app.Tab_5, 'text');
            app.muEditField.Value = '0.98';
            app.VADetaEditFieldLabel = uilabel(app.Tab_5);
            app.VADetaEditFieldLabel.Text = 'VAD阈值（eta）';
            app.VADetaEditField = uieditfield(app.Tab_5, 'text');
            app.VADetaEditField.Value = '0.15';
            app.frame_durEditFieldLabel = uilabel(app.Tab_5);
            app.frame_durEditFieldLabel.Text = '帧时长（frame_dur）';
            app.frame_durEditField = uieditfield(app.Tab_5, 'text');
            app.frame_durEditField.Value = '20';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '混响';
            app.Button_7 = uibutton(app.Tab_3, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '处理分析';
            app.Label_3 = uilabel(app.Tab_3);
            app.Label_3.Text = '混响增益';
            app.Slider_2 = uislider(app.Tab_3);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.Value = 0.2;
            app.Label_4 = uilabel(app.Tab_3);
            app.Label_4.Text = '混响延时';
            app.Slider_3 = uislider(app.Tab_3);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.Value = 0.2;
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '变音';
            app.Button_17 = uibutton(app.Tab_4, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '处理分析';
            app.Label_12 = uilabel(app.Tab_4);
            app.Label_12.Text = '基音系数';
            app.Slider_4 = uislider(app.Tab_4);
            app.Slider_4.Limits = [-1 1];
            app.Slider_5Label = uilabel(app.Tab_4);
            app.Slider_5Label.Text = '变速';
            app.Slider_5 = uislider(app.Tab_4);
            app.Slider_5.Limits = [0 3];
            app.Slider_5.Value = 1;
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '处理后序列作为新输入';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '处理后序列保存';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '文件作为输入';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '播放';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '播放';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '停止';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '停止';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '录音';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '停止';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsp_integrate_e_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end